classdef ECO_design_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        LCETab                     matlab.ui.container.Tab
        Material1DropDownLabel     matlab.ui.control.Label
        Material1DropDown          matlab.ui.control.DropDown
        Material2DropDownLabel     matlab.ui.control.Label
        Material2DropDown          matlab.ui.control.DropDown
        DrivecycleDropDownLabel    matlab.ui.control.Label
        DrivecycleDropDown         matlab.ui.control.DropDown
        MaterialDataDropDownLabel  matlab.ui.control.Label
        MaterialDataDropDown       matlab.ui.control.DropDown
        ComputeButton              matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
        ChooseresultSwitchLabel    matlab.ui.control.Label
        ChooseresultSwitch         matlab.ui.control.Switch
        TruckModelTab              matlab.ui.container.Tab
        TabGroup2                  matlab.ui.container.TabGroup
        ComputationalmodelTab      matlab.ui.container.Tab
        UIAxes                     matlab.ui.control.UIAxes
        VolvoFLTab                 matlab.ui.container.Tab
        Image                      matlab.ui.control.Image
        StructureAnalysisTab       matlab.ui.container.Tab
        StructureanalysisPanel     matlab.ui.container.Panel
    end

    
    properties (Access = private)
        drivecycle % Description
        model
        materialData
        var
    end
    
    methods (Access = private)
        
         function model = setModelMaterial(app,model,materialsData)
            model.beam.E=materialsData.youngsModulus;
            model.beam.rho=materialsData.density;
            model.beam.EProd=materialsData.productionEnergy;
            model.beam.EDisp=materialsData.disposalEnergy;
            model.beam.EEoL=materialsData.eolEnergy;
            model.beam.CO2Prod=materialsData.productionCO2;
            model.beam.CO2Disp=materialsData.disposalCO2;
            model.beam.CO2EoL=materialsData.eolCO2;
            model.beam.Cost=materialsData.price;
        end
        
        %%
        function model=computeOptimalVariable(app,model)
            %this function should determine the optimal values for the geometric
            %variable.
            model.H=abs(model.P*model.L^3/(4*model.E*model.B*model.dmax)).^(1/3);
        end
        
        %%
        function model = computeMass(app,model)
            %this function should determine the mass of the model based on its
            %geometric dimensions and material density.
            
            model.mass.chassi =  (model.beam.H(1,1) * model.beam.B(1,1) + model.beam.H(1,2) * model.beam.B(1,2) + model.beam.H(1,3) * model.beam.B(1,3)) * model.beam.L * model.beam.rho;  
            model.mass.tot = model.mass.chassi + sum([model.load.q.mass]);
        end
        
        %% Here we compute the use phase energy throw the drive cycle
        % % Taken from Life cycle energy optimisation: A proposed methodology
        function Eu = compute_LCE_drivecycle(app,drivecycle,model)
            Eu = [];
            % Roling resistanse
            Eu(1,1) = (1 - 0.15)* 9.82 * 0.01 * drivecycle.cr * model.mass.tot;
            % Energy of Inertia
            Eu(1,2) = drivecycle.ca * model.mass.tot;
            % Energy of Drag
            Eu(1,3) = 0.5 * 1.2 * model.shape.Cd * drivecycle.cd * model.shape.A;
            % sum
            Eu = sum(Eu);
            
        end

        %% Here we need to change
        function model = computeLCE(app,drivecycle,model)
            model = app.computeMass(model);
            % production phase
            Ep_kg=model.beam.EProd; %[J/kg]
            Ep=sum(Ep_kg.*model.mass.chassi);
            % Use phase
            Eu = app.compute_LCE_drivecycle(drivecycle,model);
            % Disposal phase
            Ed_kg=model.beam.EDisp; %[J/kg]
            Ed=sum(Ed_kg.*model.mass.chassi); %[J]
            % end-of-life phase
            Ee_kg=model.beam.EEoL; %[J/kg]
            Ee=sum(Ee_kg.*model.mass.chassi); %[J]
            % total
            model.LCE=[Ep Eu Ed Ee]*1e-9;
        end
        
        %% Here we need to change
        function model = computeLCCO2(app,drive,model)
            % production phase
            CO2p_kg=model.beam.CO2Prod; %[kg/kg]
            CO2p=sum(CO2p_kg.*model.mass.chassi); %[kg]
            % use phase      %simple model based on fuel efficiency
            CO2u=model.mass.tot * 0.179 * sum(drive.x); %[kg]
            % disposal phase
            CO2d_kg=model.beam.CO2Disp; %[kg/kg]
            CO2d=sum(CO2d_kg.*model.mass.chassi); %[kg]
            % end-of-life phase
            CO2e_kg=model.beam.CO2EoL; %[kg/kg]
            CO2e=sum(CO2e_kg.*model.mass.chassi); %[kg]
            % total
            model.LCCO2=[CO2p CO2u CO2d CO2e]*1e-3;
        end
        %%
        function obj = initTruck(app,material)
            
            app.var.K = find([app.materialData.names]' == material);
            
            
            obj.beam.L = 10.0;
            obj.beam.H = [0.02 0.1 0.02];
            obj.beam.B = [0.1 0.02 0.1]; % this is not the optimum height, it could be minimised with subject to the load. 
            obj.beam.E = app.materialData.youngsModulus(app.var.K,1);
            obj.beam.rho = app.materialData.density(app.var.K,1);
            
            obj.axel.rad = 0.5;
            obj.axel.Crr = 0.01;
            obj.axel.R(1).x = 0.75;
            obj.axel.R(2).x = 6;
            
            obj.shape.B = 2.0;
            obj.shape.Cd = 0.5;
            
            obj.load.q(2).x = [1, 4];
            obj.load.q(2).mass = 5e3;
            obj.load.q(2).rho = 5e3;
            
            obj.load.q(3).x = [3, 9];
            obj.load.q(3).mass = 5e3;
            obj.load.q(3).rho = 1e2;
            
        end
        
        %%
        function obj=updateDependentVars(app,obj)
            I0=obj.beam.B.*obj.beam.H.^3/12;
            
            d=([0 cumsum(obj.beam.H(1:end-1))]+ obj.beam.H/2-sum(obj.beam.H)/2);
            
            A=obj.beam.B.*obj.beam.H;
            I=I0+A.*d.^2;
            obj.beam.A=sum(A);
            obj.beam.I=sum(I);
            obj.load.q(1).x = [0 obj.beam.L];
            obj.load.q(1).fun = str2func(['@(x)',mat2str(-obj.beam.rho*obj.beam.A*9.81),'+0.*x']);  %Point load
            for i = 2:numel(obj.load.q)
                obj.load.q(i).fun = str2func(['@(x)',mat2str(-obj.load.q(i).mass/abs(diff(obj.load.q(i).x))*9.81),'+0.*x']);
                obj.load.q(i).H = obj.load.q(i).mass/obj.load.q(i).rho/abs(diff(obj.load.q(i).x))/obj.shape.B;
            end
            obj.load.H = max([obj.load.q.H]);
            obj.shape.H = obj.axel.rad + sum(obj.beam.H) + obj.load.H;
            obj.shape.L = obj.beam.L;
            obj.shape.A = sqrt(obj.shape.L^2+obj.shape.H^2);
        end
        
        %%
        function dispTruck(app,obj)
            C=colormap(app.UIAxes);
            r=obj.axel.rad;
            for i = 1:2 % Displays wheels
                x = obj.axel.R(i).x;
                R= rectangle(app.UIAxes,'Position',[x-r 0 2*r 2*r],'Curvature',[1 1]);
                set(R,'Facecolor','k')
            end
            h0 = 2*r;
            for i = 1:3 %Display the sandwich model
                h = obj.beam.H(i);
                L = obj.beam.L;
                R=rectangle(app.UIAxes,'Position',[0 h0 L h]);
                set(R,'Facecolor','g')
                h0 = h0 + h;
            end
            for i = 2:numel(obj.load.q) %display the load
                R=rectangle(app.UIAxes,'Position',[obj.load.q(i).x(1) h0 obj.load.q(i).x(2)-obj.load.q(i).x(1) obj.load.q(i).H]);
                set(R,'Facecolor',C(50*(i-1)+1,:))
            end
            rectangle(app.UIAxes,'Position',[0 r obj.beam.L obj.shape.H],'LineStyle','--')
            axis(app.UIAxes,'equal')
            xlabel(app.UIAxes,'l [m]'), ylabel(app.UIAxes,'h [m]')
            title(app.UIAxes,'Truck Model')
            
        end
        
        %%
        function obj = computeReactions(app,obj)
            F = arrayfun(@(y) integral(y.fun,y.x(1),y.x(2)),obj.load.q);
            
            M = arrayfun(@(y) integral(@(x)y.fun(x).*x,y.x(1),y.x(2)),obj.load.q);
            
            r = [obj.axel.R(:).x];
            
            invA = [-r(2)/(r(1) - r(2)),  1/(r(1) - r(2))
                r(1)/(r(1) - r(2)), -1/(r(1) - r(2))];
            a = -invA*[sum(F);sum(M)];
            obj.axel.R(1).a = a(1);
            obj.axel.R(2).a = a(2);
        end
        
        %%
        function obj = evaluateBeamFuncs(app,obj)
            
            Q=sum([arrayfun(@(y) y.a*heaviside(sym('x')-y.x),obj.axel.R),...
                arrayfun(@(y) int(sym(y.fun),y.x(1),sym('x'))*heaviside(sym('x')-y.x(1)),obj.load.q),...
                arrayfun(@(y) -int(sym(y.fun),y.x(2),sym('x'))*heaviside(sym('x')-y.x(2)),obj.load.q)]);
            
            
            M=int(Q,0,sym('x'));
            T=(int(M,0,sym('x'))-int(int(M,0,sym('x')),obj.axel.R(1).x,obj.axel.R(2).x)/(obj.axel.R(2).x-obj.axel.R(1).x))/obj.beam.E/obj.beam.I;
            W=int(T,obj.axel.R(1).x,sym('x'));
            obj.beam.shear = matlabFunction(Q);
            obj.beam.moment = matlabFunction(M);
            obj.beam.curve = matlabFunction(T);
            obj.beam.delta = matlabFunction(W);
        end
        
        %% display beam functions
        function dispBeamFuncs(app,model_1,model_2,legends)
            t = tiledlayout(app.StructureanalysisPanel,2,2); 
            
            ax1 = nexttile(t);
            fplot(ax1,model_1.beam.shear,[0 model_1.beam.L])
            hold(ax1,"on")
            fplot(ax1,model_2.beam.shear,[0 model_2.beam.L])
            grid (ax1,"on"), xlabel(ax1,'x'),ylabel(ax1,'shear force'), legend(ax1,legends),
            hold(ax1,"off")
            
            ax1 = nexttile(t);
            fplot(ax1,model_1.beam.moment,[0 model_1.beam.L])
            hold(ax1,"on")
            fplot(ax1,model_2.beam.moment,[0 model_2.beam.L])
            grid (ax1,"on"), xlabel(ax1,'x'),ylabel(ax1,'bending moment'),legend(ax1,legends),
            hold(ax1,"off")
            
            ax1 = nexttile(t);
            fplot(ax1,model_1.beam.curve,[0 model_1.beam.L])
            hold(ax1,"on")
            fplot(ax1,model_2.beam.curve,[0 model_2.beam.L])
            grid(ax1,"on"), xlabel(ax1,'x'),ylabel(ax1,'curvature'), legend(ax1,legends),
            hold(ax1,"off")
            
            ax1 = nexttile(t);
            fplot(ax1,model_1.beam.delta,[0 model_1.beam.L])
            hold(ax1,"on")
            fplot(ax1,model_2.beam.delta,[0 model_2.beam.L])
            grid (ax1,"on"), xlabel(ax1,'x'),ylabel(ax1,'delta'),legend(ax1,legends),
            hold(ax1,"off")
        end


        %%
        function materialData = importMaterial_Data(app)
            % Setup the Import Options and import the data
            clear opts
            opts = spreadsheetImportOptions("NumVariables", 13);
            
            % Specify sheet and range
            opts.Sheet = "Sheet1";
            opts.DataRange = "A3:M11";
            
            % Specify column names and types
            opts.VariableNames = ["names", "price", "density", "youngsModulus", "poissonsRatio", "yieldStrength", "tensileStrength", "productionEnergy", "productionCO2", "disposalEnergy", "disposalCO2", "eolEnergy", "eolCO2"];
            opts.VariableTypes = ["string", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double"];
            
            % Specify variable properties
            opts = setvaropts(opts, "names", "WhitespaceRule", "preserve");
            opts = setvaropts(opts, "names", "EmptyFieldRule", "auto");
            
            % Import the data
            tabledata = [app.MaterialDataDropDown.Value];
            materialData=readtable(tabledata,opts, "UseExcel", false);
            %materialData=table2struct(materialData);
            
        end
        
        %%
        function drivecycle = import_data(app)
            
            if ~isstruct(app.DrivecycleDropDown.Value)
                name = app.DrivecycleDropDown.Value;
                data = [];
                data.name = name;
            end
            
            tabledata = [app.DrivecycleDropDown.Value];
            t = readtable(tabledata);
            vel = t.vel/3.6; %km/h to m/s
            t = t.t;
            n=length(t);
            D=spdiags(repmat([-0.5 0 0.5],n,1),-1:1,n,n)+sparse([1 1 n n],[1 2 n-1 n],[-1 0.5 -0.5 1]);
            dt=D*t;
            dx=vel.*dt;
            x=cumsum(dx);
            acc=D*vel./dt;
            r=sum(acc<0)/numel(acc);
            acc(acc<0)=0;
            data.t=t;
            data.x=x;
            data.vel=vel;
            data.acc=acc;
            data.r=r;
            data.cr=sum(dx);
            data.cd=sum(vel.^3.*dt);
            data.ca=sum(acc.*vel.*dt);
            drivecycle = data;
            
        end
        %%
        function bargraf(app,result,showtotal)

            if nargin==3
                cla(app.UIAxes2,"reset");
                result(1).values=[result(1).values;sum([result(1).values],1)];
                bar(app.UIAxes2,result(1).values,'grouped')
                result(1).xlabel=[result(1).xlabel,{'Total'}];
                bar(app.UIAxes2,result(1).values,'grouped')
                a=axis(app.UIAxes2);
                hold(app.UIAxes2,"on")
                plot(app.UIAxes2,repmat(size(result(1).values,1)-0.5,1,2),[a(3) a(4)]*10,'-k')
                axis(app.UIAxes2,a)
                hold (app.UIAxes2,"off")
                
            elseif size(result(1).values,1)==1 & size(result(1).values,2)>1
                cla(app.UIAxes2,"reset");
                bar(app.UIAxes2,[result(1).values;result(1).values],'grouped')
                a=axis(app.UIAxes2);
                a(2)=a(2)-1;
                axis(app.UIAxes2,a)
            else
                bar(app.UIAxes2,result(1).values,'grouped')
            end
            
            title(app.UIAxes2, result(1).title);
            %xlabel(app.UIAxes, result(1).xlabel)
            ylabel(app.UIAxes2, result(1).ylabel)
            legend(app.UIAxes2,{result.legend},'location','bestoutside');
            set(app.UIAxes2,'xticklabel',result(1).xlabel)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         DrivecycleDropDownValueChanged(app);
         
         app.materialData = importMaterial_Data(app);
         
         
         Material1DropDownValueChanged(app);
         
         
        end

        % Value changed function: DrivecycleDropDown, 
        % MaterialDataDropDown
        function DrivecycleDropDownValueChanged(app, event)
            D = dir("WLTP*.xlsx");
            app.DrivecycleDropDown.Items = {D.name};
            S = dir("materialData.xlsx");
            app.MaterialDataDropDown.Items = {S.name}; 

        end

        % Value changed function: Material1DropDown, 
        % Material2DropDown
        function Material1DropDownValueChanged(app, event)
            app.Material1DropDown.Items = app.materialData.names; 
            app.Material2DropDown.Items = app.materialData.names; 
        end

        % Callback function: ChooseresultSwitch, ComputeButton
        function ComputeButtonPushed(app, event)
            app.Image.ImageSource = imread('https://www.volvotrucks.se/sv-se/trucks/trucks/volvo-fl/volvo-fl-electric/_jcr_content/root/responsivegrid/columncontrol_1535929993/parsys1/image.coreimg.jpeg/1601564446161/volvo-trucks-fl-electric-infrastructure.jpeg','jpeg');
            
            
            DrivecycleDropDownValueChanged(app);
            app.drivecycle = import_data(app); 
            
            
            value_1 = app.Material1DropDown.Value; 
            value_2 = app.Material2DropDown.Value; 
            comparison_type = app.ChooseresultSwitch.Value; 
            
            legends = {value_1 value_2}; 
            
            app.model.model_1 = app.initTruck(value_1);
            app.model.model_1 = app.setModelMaterial(app.model.model_1,app.materialData(app.var.K,:));
            app.model.model_1 = app.updateDependentVars(app.model.model_1);
            app.model.model_1 = app.computeMass(app.model.model_1);
            app.model.model_1 = app.computeLCE(app.drivecycle,app.model.model_1);
            app.model.model_1 = app.computeLCCO2(app.drivecycle,app.model.model_1);
            app.model.model_1 = app.computeReactions(app.model.model_1);
            app.model.model_1 = app.evaluateBeamFuncs(app.model.model_1);

            app.model.model_2 = app.initTruck(value_2);
            app.model.model_2 = app.setModelMaterial(app.model.model_2,app.materialData(app.var.K,:));
            app.model.model_2 = app.updateDependentVars(app.model.model_2);
            app.model.model_2 = app.computeMass(app.model.model_2);
            app.model.model_2 = app.computeLCE(app.drivecycle,app.model.model_2);
            app.model.model_2 = app.computeLCCO2(app.drivecycle,app.model.model_2);
            app.model.model_2 = app.computeReactions(app.model.model_2);
            app.model.model_2 = app.evaluateBeamFuncs(app.model.model_2);
            
            app.dispBeamFuncs(app.model.model_1,app.model.model_2,legends); 
            app.dispTruck(app.model.model_1);
            
            switch comparison_type
                case 'CO2'
                    Luring = [app.model.model_1.LCCO2', app.model.model_2.LCCO2'];
                    bargraf(app,struct('title','CO_2','xlabel',{{'Prod','Use','EoL Pot'}},'ylabel','[kg]','legend',legends,'values',Luring),1);
                otherwise
                    Luring = [app.model.model_1.LCE', app.model.model_2.LCE'];
                    bargraf(app,struct('title','Energy','xlabel',{{'Prod','Use','EoL Pot'}},'ylabel','[J]','legend',legends,'values',Luring),1);
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LCETab = uitab(app.TabGroup);
            app.LCETab.Title = 'LCE';
            app.Material1DropDownLabel = uilabel(app.LCETab);
            app.Material1DropDownLabel.Text = 'Material #1';
            app.Material1DropDown = uidropdown(app.LCETab);
            app.Material1DropDown.ValueChangedFcn = createCallbackFcn(app, @Material1DropDownValueChanged, true);
            app.Material2DropDownLabel = uilabel(app.LCETab);
            app.Material2DropDownLabel.Text = 'Material #2';
            app.Material2DropDown = uidropdown(app.LCETab);
            app.Material2DropDown.ValueChangedFcn = createCallbackFcn(app, @Material1DropDownValueChanged, true);
            app.DrivecycleDropDownLabel = uilabel(app.LCETab);
            app.DrivecycleDropDownLabel.Text = 'Drive cycle';
            app.DrivecycleDropDown = uidropdown(app.LCETab);
            app.DrivecycleDropDown.ValueChangedFcn = createCallbackFcn(app, @DrivecycleDropDownValueChanged, true);
            app.MaterialDataDropDownLabel = uilabel(app.LCETab);
            app.MaterialDataDropDownLabel.Text = 'Material Data';
            app.MaterialDataDropDown = uidropdown(app.LCETab);
            app.MaterialDataDropDown.Items = {};
            app.MaterialDataDropDown.ValueChangedFcn = createCallbackFcn(app, @DrivecycleDropDownValueChanged, true);
            app.MaterialDataDropDown.Value = {};
            app.ComputeButton = uibutton(app.LCETab, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.UIAxes2 = uiaxes(app.LCETab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.ChooseresultSwitchLabel = uilabel(app.LCETab);
            app.ChooseresultSwitchLabel.Text = 'Choose result';
            app.ChooseresultSwitch = uiswitch(app.LCETab, 'slider');
            app.ChooseresultSwitch.Items = {'CO2', 'Energy'};
            app.ChooseresultSwitch.ValueChangedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ChooseresultSwitch.Value = 'CO2';
            app.TruckModelTab = uitab(app.TabGroup);
            app.TruckModelTab.Title = 'Truck Model';
            app.TabGroup2 = uitabgroup(app.TruckModelTab);
            app.ComputationalmodelTab = uitab(app.TabGroup2);
            app.ComputationalmodelTab.Title = 'Computational model';
            app.UIAxes = uiaxes(app.ComputationalmodelTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.VolvoFLTab = uitab(app.TabGroup2);
            app.VolvoFLTab.Title = 'Volvo FL';
            app.Image = uiimage(app.VolvoFLTab);
            app.StructureAnalysisTab = uitab(app.TabGroup);
            app.StructureAnalysisTab.Title = 'Structure Analysis';
            app.StructureanalysisPanel = uipanel(app.StructureAnalysisTab);
            app.StructureanalysisPanel.Title = 'Structure analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECO_design_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end